classdef MATHLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SELECTOPERATIONPanel       matlab.ui.container.Panel
        DifferentiationCheckBox    matlab.ui.control.CheckBox
        IntegrationCheckBox        matlab.ui.control.CheckBox
        LaplaceTransformCheckBox   matlab.ui.control.CheckBox
        FUNCTIONEditFieldLabel     matlab.ui.control.Label
        FUNCTIONEditField          matlab.ui.control.EditField
        OrderEditFieldLabel        matlab.ui.control.Label
        OrderEditField             matlab.ui.control.NumericEditField
        HigherLimitEditFieldLabel  matlab.ui.control.Label
        HigherLimitEditField       matlab.ui.control.NumericEditField
        STARTButton                matlab.ui.control.Button
        InputFunctionAxes          matlab.ui.control.UIAxes
        DerivativeAxes             matlab.ui.control.UIAxes
        LowerLimitEditFieldLabel   matlab.ui.control.Label
        LowerLimitEditField        matlab.ui.control.NumericEditField
        NOTETextArea               matlab.ui.control.TextArea
        NOTELabel                  matlab.ui.control.Label
        DifferentiationTypesPanel  matlab.ui.container.Panel
        SymbolicCheckBox           matlab.ui.control.CheckBox
        CentralCheckBox            matlab.ui.control.CheckBox
        ForwardCheckBox            matlab.ui.control.CheckBox
        BackwardCheckBox           matlab.ui.control.CheckBox
        IntegrationTypesPanel      matlab.ui.container.Panel
        Symbolic_IntCheckBox       matlab.ui.control.CheckBox
        Simpsons38ruleCheckBox     matlab.ui.control.CheckBox
        Simpsons13ruleCheckBox     matlab.ui.control.CheckBox
        TrapizoidalmethodCheckBox  matlab.ui.control.CheckBox
        LTTypesPanel               matlab.ui.container.Panel
        LaplaceCheckBox            matlab.ui.control.CheckBox
        InverseLaplaceCheckBox     matlab.ui.control.CheckBox
        RESULTLabel                matlab.ui.control.Label
        ResultEditField            matlab.ui.control.EditField
        MATHLABLabel               matlab.ui.control.Label
        StepSizeEditFieldLabel     matlab.ui.control.Label
        StepSizeEditField          matlab.ui.control.NumericEditField
        GeneratePlotButton         matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
     if app.SymbolicCheckBox.Value == 1;
        syms x; % Create symbolic variable
        f = str2sym(app.FUNCTIONEditField.Value);% Get the symbolic expression from the app
        order = app.OrderEditField.Value; % Get the order of differentiation from the app
        result = diff(f,x, order); % Differentiate the function
        app.ResultEditField.Value = char(result); % Display the result
 
        
     elseif  app.CentralCheckBox.Value== 1;
         
 % Input values
b = app.HigherLimitEditField.Value;
a = app.LowerLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x) - 1;
y = str2func(['@(x)' app.FUNCTIONEditField.Value]); % convert string to function handle

% Initialize the output array
dy = zeros(1, n);

% Compute the central difference
for i = 2:n
    dy(i-1) = (y(x(i+1)) - y(x(i-1)))/(2*h);
end

% Update the result
app.ResultEditField.Value = num2str(dy);
app.GeneratePlotButton.Enable='on';


     elseif  app.ForwardCheckBox.Value==1;
% Input values
b = app.HigherLimitEditField.Value;
a = app.LowerLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x) - 1;
y = str2func(['@(x)' app.FUNCTIONEditField.Value]); % convert string to function handle

% Initialize the output array
dy = zeros(1, n);

% Compute the forward difference
for i = 2:n
    dy(i) = (y(x(i+1)) - y(x(i)))/h;
end

% Update the result
app.ResultEditField.Value = num2str(dy);
app.GeneratePlotButton.Enable='on';


elseif app.BackwardCheckBox.Value==1;
                 
% Input values
b = app.HigherLimitEditField.Value;
a = app.LowerLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
y = str2func(['@(x)' app.FUNCTIONEditField.Value]); % convert string to function handle

% Initialize the output array
dy = zeros(1, n);

% Compute the backward difference
for i = 2:n
    dy(i-1) = (y(x(i)) - y(x(i-1)))/h;
end

% Update the result
app.ResultEditField.Value = num2str(dy);
app.GeneratePlotButton.Enable='on';

     elseif app.Symbolic_IntCheckBox.Value== 1;
     syms x; % Create symbolic variable
    f = str2sym(app.FUNCTIONEditField.Value);% Get the symbolic expression from the app
    order = app.OrderEditField.Value;
    for i=0:order
          if i==0
           result =char(f);  
          else
           result = int(f,x);
           f = result;
          end
          end 
 app.ResultEditField.Value = char(result); % Display the result
 
 
elseif app.Simpsons38ruleCheckBox.Value==1
         
a = app.LowerLimitEditField.Value;
b = app.HigherLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
f = str2func(['@(x)' app.FUNCTIONEditField.Value]);
s = 0;

for i = 1:n
    if i == 1 || i == n
        s = s + f(x(i));
    elseif mod(i-1,3) == 0 % i-1 to compensate for the fact that the indices start from 1
        s = s + 2*f(x(i));
    else
        s = s + 3*f(x(i));
    end
end

I = (3*h/8)*s;

% Compute the cumulative integral of the function f(x)

app.ResultEditField.Value = num2str(I);
app.GeneratePlotButton.Enable='on';


elseif app.Simpsons13ruleCheckBox.Value==1
         
a = app.LowerLimitEditField.Value;
b = app.HigherLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
f = str2func(['@(x)' app.FUNCTIONEditField.Value]);
s = 0;

for i = 1:n
    if i == 1 || i == n
        s = s + f(x(i));
    elseif mod(i-1,3) == 0 % i-1 to compensate for the fact that the indices start from 1
        s = s + 2*f(x(i));
    else
        s = s + 3*f(x(i));
    end
end

I=(h/3)*s;

% Compute the cumulative integral of the function f(x)

app.ResultEditField.Value = num2str(I);
app.GeneratePlotButton.Enable='on';


     elseif app.TrapizoidalmethodCheckBox.Value==1
         
         
a = app.LowerLimitEditField.Value;
b = app.HigherLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
f = str2func(['@(x)' app.FUNCTIONEditField.Value]);
s = 0;

for i = 1:n
    if i == 1 || i == n
        s = s + f(x(i));
    else
        s = s + 2*f(x(i));
    
end
end

I=(h/2)*s;
% Compute the cumulative integral of the function f(x)

app.ResultEditField.Value = num2str(I);
app.GeneratePlotButton.Enable='on';


     elseif app.LaplaceCheckBox.Value==1;
         syms t s;
        f = str2sym(app.FUNCTIONEditField.Value);
        % Compute the Laplace transform of the input function
        result= laplace(f, t, s);
    % Display the result
    app.ResultEditField.Value = char(result);
 
     elseif app.InverseLaplaceCheckBox.Value==1;
        syms s t;
        f = str2sym(app.FUNCTIONEditField.Value);
        % Compute the inverse Laplace transform of the input function
        result= ilaplace(f, s, t);
    % Display the result
    app.ResultEditField.Value = char(result);
   
     else  
             app.ResultEditField.Value= "NO MATCH FOUND :("
     end
        end

        % Value changed function: DifferentiationCheckBox
        function DifferentiationCheckBoxValueChanged(app, event)
            value = app.DifferentiationCheckBox.Value;
            switch value
                case 0
                    app.DifferentiationTypesPanel.Visible='off';
                case 1
                    app.DifferentiationTypesPanel.Visible='on';
            
            end
        end

        % Value changed function: IntegrationCheckBox
        function IntegrationCheckBoxValueChanged(app, event)
            value = app.IntegrationCheckBox.Value;
                        
            switch value
                case 0
                    app.IntegrationTypesPanel.Visible='off';
                case 1
                    app.IntegrationTypesPanel.Visible='on';
            
            end
            
        end

        % Value changed function: SymbolicCheckBox
        function SymbolicCheckBoxValueChanged(app, event)
            value = app.SymbolicCheckBox.Value;
             switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.InputFunctionAxes.Visible='off';
                    app.DerivativeAxes.Visible='off';
                    
                case 1
                    
                    app.OrderEditField.Enable='on';
                    app.STARTButton.Enable='on';
                  
                end
        end

        % Value changed function: CentralCheckBox
        function CentralCheckBoxValueChanged(app, event)
            value = app.CentralCheckBox.Value;
             switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.InputFunctionAxes.Visible='off';
                    app.DerivativeAxes.Visible='off';
                     app.LowerLimitEditField.Enable='off';
                    app.StepSizeEditField.Enable='off';

                case 1
                    
                   app.HigherLimitEditField.Enable='on';
                    app.LowerLimitEditField.Enable='on';
                    app.STARTButton.Enable='on';
                     app.LowerLimitEditField.Enable='on';
                    app.StepSizeEditField.Enable='on';
           
             end
        end

        % Value changed function: LaplaceTransformCheckBox
        function LaplaceTransformCheckBoxValueChanged(app, event)
            value = app.LaplaceTransformCheckBox.Value;
           
                        
            switch value
                case 0
                    app.LTTypesPanel.Visible='off';
                case 1
                    app.LTTypesPanel.Visible='on';
            
            end


        end

        % Value changed function: ForwardCheckBox
        function ForwardCheckBoxValueChanged(app, event)
            value = app.ForwardCheckBox.Value;
            switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.InputFunctionAxes.Visible='off';
                    app.DerivativeAxes.Visible='off';
                     app.LowerLimitEditField.Enable='off';
                    app.StepSizeEditField.Enable='off';

                case 1
                    
                   app.HigherLimitEditField.Enable='on';
                    app.LowerLimitEditField.Enable='on';
                    app.STARTButton.Enable='on';
                     app.LowerLimitEditField.Enable='on';
                    app.StepSizeEditField.Enable='on';
           
             end
            
        end

        % Value changed function: BackwardCheckBox
        function BackwardCheckBoxValueChanged(app, event)
            value = app.BackwardCheckBox.Value;
            switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.InputFunctionAxes.Visible='off';
                    app.DerivativeAxes.Visible='off';
                     app.StepSizeEditField.Enable='off';

                case 1
                    
                   app.HigherLimitEditField.Enable='on';
                    app.LowerLimitEditField.Enable='on';
                    app.STARTButton.Enable='on';
                    app.StepSizeEditField.Enable='on';
           
             end
        end

        % Value changed function: Symbolic_IntCheckBox
        function Symbolic_IntCheckBoxValueChanged(app, event)
            value = app.Symbolic_IntCheckBox.Value;
             switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.InputFunctionAxes.Visible='off';
                    app.DerivativeAxes.Visible='off';
                    
                case 1
                    
                    app.OrderEditField.Enable='on';
                    app.STARTButton.Enable='on';
                  
                end
            
        end

        % Value changed function: LaplaceCheckBox
        function LaplaceCheckBoxValueChanged(app, event)
            value = app.LaplaceCheckBox.Value;
            
            switch value
                case 0
                   
                    app.STARTButton.Enable='off';
                    app.InputFunctionAxes.Visible='off';
                    app.DerivativeAxes.Visible='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';

                case 1
                    
                    app.STARTButton.Enable='on';
           
             end
            
        end

        % Value changed function: InverseLaplaceCheckBox
        function InverseLaplaceCheckBoxValueChanged(app, event)
            value = app.InverseLaplaceCheckBox.Value;
            switch value
                case 0
                   
                    app.STARTButton.Enable='off';
                     app.InputFunctionAxes.Visible='off';
                    app.DerivativeAxes.Visible='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';

                case 1
                    
                    app.STARTButton.Enable='on';
           
             end
            
        end

        % Value changed function: Simpsons38ruleCheckBox
        function Simpsons38ruleCheckBoxValueChanged(app, event)
            value = app.Simpsons38ruleCheckBox.Value;
                   
            switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.StepSizeEditField.Enable='off';

                case 1
                    
                   app.HigherLimitEditField.Enable='on';
                    app.LowerLimitEditField.Enable='on';
                    app.STARTButton.Enable='on';
                    app.StepSizeEditField.Enable='on';
            end
           
        end

        % Value changed function: Simpsons13ruleCheckBox
        function Simpsons13ruleCheckBoxValueChanged(app, event)
            value = app.Simpsons13ruleCheckBox.Value;
            switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.StepSizeEditField.Enable='off';

                case 1
                    
                   app.HigherLimitEditField.Enable='on';
                    app.LowerLimitEditField.Enable='on';
                    app.STARTButton.Enable='on';
                    app.StepSizeEditField.Enable='on';
            end
            
        end

        % Value changed function: TrapizoidalmethodCheckBox
        function TrapizoidalmethodCheckBoxValueChanged(app, event)
            value = app.TrapizoidalmethodCheckBox.Value;
            
            
            switch value
                case 0
                    app.OrderEditField.Enable='off';
                    app.STARTButton.Enable='off';
                    app.HigherLimitEditField.Enable='off';
                    app.LowerLimitEditField.Enable='off';
                    app.StepSizeEditField.Enable='off';

                case 1
                    
                   app.HigherLimitEditField.Enable='on';
                    app.LowerLimitEditField.Enable='on';
                    app.STARTButton.Enable='on';
                    app.StepSizeEditField.Enable='on';
            end
        end

        % Value changed function: GeneratePlotButton
        function GeneratePlotButtonValueChanged(app, event)
if app.SymbolicCheckBox.Value == 1;
        syms x; % Create symbolic variable
        f = str2sym(app.FUNCTIONEditField.Value);% Get the symbolic expression from the app
        order = app.OrderEditField.Value; % Get the order of differentiation from the app
        result = diff(f,x, order); % Differentiate the function
        app.ResultEditField.Value = char(result); % Display the result
 
        
     elseif  app.CentralCheckBox.Value== 1;
         
 % Input values
b = app.HigherLimitEditField.Value;
a = app.LowerLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x) - 1;
y = str2func(['@(x)' app.FUNCTIONEditField.Value]); % convert string to function handle

% Initialize the output array
dy = zeros(1, n);

% Compute the central difference
for i = 2:n
    dy(i-1) = (y(x(i+1)) - y(x(i-1)))/(2*h);
end

app.InputFunctionAxes.Visible='on';
app.DerivativeAxes.Visible='on';

% Plot the input function and its derivative
plot(app.InputFunctionAxes,x, y(x));
title(app.InputFunctionAxes, 'Input Function');
plot(app.DerivativeAxes,x(2:end-1),dy(2:end));
title(app.DerivativeAxes, 'Derivative');


     elseif  app.ForwardCheckBox.Value==1;
% Input values
b = app.HigherLimitEditField.Value;
a = app.LowerLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x) - 1;
y = str2func(['@(x)' app.FUNCTIONEditField.Value]); % convert string to function handle

% Initialize the output array
dy = zeros(1, n);

% Compute the forward difference
for i = 2:n
    dy(i) = (y(x(i+1)) - y(x(i)))/h;
end

app.InputFunctionAxes.Visible='on';
app.DerivativeAxes.Visible='on';
% Plot the input function and its derivative
plot(app.InputFunctionAxes,x, y(x));
title(app.InputFunctionAxes, 'Input Function');
plot(app.DerivativeAxes,x(2:end-1),dy(2:end));
title(app.DerivativeAxes, 'Derivative');


elseif app.BackwardCheckBox.Value==1;
                 
% Input values
b = app.HigherLimitEditField.Value;
a = app.LowerLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
y = str2func(['@(x)' app.FUNCTIONEditField.Value]); % convert string to function handle

% Initialize the output array
dy = zeros(1, n);

% Compute the backward difference
for i = 2:n
    dy(i-1) = (y(x(i)) - y(x(i-1)))/h;
end

app.InputFunctionAxes.Visible='on';
app.DerivativeAxes.Visible='on';
% Plot the input function and its derivative
plot(app.InputFunctionAxes,x, y(x));
title(app.InputFunctionAxes, 'Input Function');
plot(app.DerivativeAxes,x(2:end),dy(2:end));
title(app.DerivativeAxes, 'Derivative');

     elseif app.Symbolic_IntCheckBox.Value== 1;
     syms x; % Create symbolic variable
    f = str2sym(app.FUNCTIONEditField.Value);% Get the symbolic expression from the app
    order = app.OrderEditField.Value;
    for i=0:order
          if i==0
           result =char(f);  
          else
           result = int(f,x);
           f = result;
          end
          end 


elseif app.Simpsons38ruleCheckBox.Value==1
         
a = app.LowerLimitEditField.Value;
b = app.HigherLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
f = str2func(['@(x)' app.FUNCTIONEditField.Value]);
s = 0;

for i = 1:n
    if i == 1 || i == n
        s = s + f(x(i));
    elseif mod(i-1,3) == 0 % i-1 to compensate for the fact that the indices start from 1
        s = s + 2*f(x(i));
    else
        s = s + 3*f(x(i));
    end
end

I = (3*h/8)*s;

% Compute the cumulative integral of the function f(x)
cumulative_int = cumtrapz(x, f(x));


app.InputFunctionAxes.Visible='on';
app.DerivativeAxes.Visible='on';
% Plot the input function and its derivative
plot(app.InputFunctionAxes,x, f(x));
title(app.InputFunctionAxes, 'Input Function');

plot(app.DerivativeAxes,x,cumulative_int);
title(app.DerivativeAxes, 'Integration');


elseif app.Simpsons13ruleCheckBox.Value==1
         
a = app.LowerLimitEditField.Value;
b = app.HigherLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
f = str2func(['@(x)' app.FUNCTIONEditField.Value]);
s = 0;

for i = 1:n
    if i == 1 || i == n
        s = s + f(x(i));
    elseif mod(i-1,3) == 0 % i-1 to compensate for the fact that the indices start from 1
        s = s + 2*f(x(i));
    else
        s = s + 3*f(x(i));
    end
end

I=(h/3)*s;

% Compute the cumulative integral of the function f(x)
cumulative_int = cumtrapz(x, f(x));


app.InputFunctionAxes.Visible='on';
app.DerivativeAxes.Visible='on';
% Plot the input function and its derivative
plot(app.InputFunctionAxes,x, f(x));
title(app.InputFunctionAxes, 'Input Function');

plot(app.DerivativeAxes,x,cumulative_int);
title(app.DerivativeAxes, 'Integration');

     elseif app.TrapizoidalmethodCheckBox.Value==1
         
         
a = app.LowerLimitEditField.Value;
b = app.HigherLimitEditField.Value;
h = app.StepSizeEditField.Value;
x = a:h:b;
n = length(x);
f = str2func(['@(x)' app.FUNCTIONEditField.Value]);
s = 0;

for i = 1:n
    if i == 1 || i == n
        s = s + f(x(i));
    else
        s = s + 2*f(x(i));
    
end
end

I=(h/2)*s;
% Compute the cumulative integral of the function f(x)
cumulative_int = cumtrapz(x, f(x));


app.InputFunctionAxes.Visible='on';
app.DerivativeAxes.Visible='on';
% Plot the input function and its derivative
plot(app.InputFunctionAxes,x, f(x));
title(app.InputFunctionAxes, 'Input Function');

plot(app.DerivativeAxes,x,cumulative_int);
title(app.DerivativeAxes, 'Integration');


     elseif app.LaplaceCheckBox.Value==1;
         syms t s;
        f = str2sym(app.FUNCTIONEditField.Value);
        % Compute the Laplace transform of the input function
        result= laplace(f, t, s);
    % Display the result
    
 
     elseif app.InverseLaplaceCheckBox.Value==1;
        syms s t;
        f = str2sym(app.FUNCTIONEditField.Value);
        % Compute the inverse Laplace transform of the input function
        result= ilaplace(f, s, t);
    % Display the result
    
   
     else  
             app.ResultEditField.Value= "NO MATCH FOUND :("
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SELECTOPERATIONPanel = uipanel(app.UIFigure);
            app.SELECTOPERATIONPanel.Title = 'SELECT OPERATION';
            app.DifferentiationCheckBox = uicheckbox(app.SELECTOPERATIONPanel);
            app.DifferentiationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DifferentiationCheckBoxValueChanged, true);
            app.DifferentiationCheckBox.Text = 'Differentiation';
            app.IntegrationCheckBox = uicheckbox(app.SELECTOPERATIONPanel);
            app.IntegrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntegrationCheckBoxValueChanged, true);
            app.IntegrationCheckBox.Text = 'Integration ';
            app.LaplaceTransformCheckBox = uicheckbox(app.SELECTOPERATIONPanel);
            app.LaplaceTransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaplaceTransformCheckBoxValueChanged, true);
            app.LaplaceTransformCheckBox.Text = 'Laplace Transform';
            app.FUNCTIONEditFieldLabel = uilabel(app.UIFigure);
            app.FUNCTIONEditFieldLabel.Text = 'FUNCTION';
            app.FUNCTIONEditField = uieditfield(app.UIFigure, 'text');
            app.FUNCTIONEditField.Value = '<INPUT FUNCTION>';
            app.OrderEditFieldLabel = uilabel(app.UIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderEditField.Enable = 'off';
            app.OrderEditField.Value = 1;
            app.HigherLimitEditFieldLabel = uilabel(app.UIFigure);
            app.HigherLimitEditFieldLabel.Text = 'Higher Limit';
            app.HigherLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.HigherLimitEditField.Enable = 'off';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.InputFunctionAxes = uiaxes(app.UIFigure);
            title(app.InputFunctionAxes, 'Title')
            xlabel(app.InputFunctionAxes, 'X')
            ylabel(app.InputFunctionAxes, 'Y')
            app.InputFunctionAxes.Visible = 'off';
            app.DerivativeAxes = uiaxes(app.UIFigure);
            title(app.DerivativeAxes, 'Title')
            xlabel(app.DerivativeAxes, 'X')
            ylabel(app.DerivativeAxes, 'Y')
            app.DerivativeAxes.Visible = 'off';
            app.LowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerLimitEditField.Enable = 'off';
            app.NOTETextArea = uitextarea(app.UIFigure);
            app.NOTETextArea.Value = {'* Differentiation & Integration is performed with respect to ''x'''; ''; ''; ''};
            app.NOTELabel = uilabel(app.UIFigure);
            app.NOTELabel.Text = 'NOTE';
            app.DifferentiationTypesPanel = uipanel(app.UIFigure);
            app.DifferentiationTypesPanel.Title = 'Differentiation Types';
            app.DifferentiationTypesPanel.Visible = 'off';
            app.SymbolicCheckBox = uicheckbox(app.DifferentiationTypesPanel);
            app.SymbolicCheckBox.ValueChangedFcn = createCallbackFcn(app, @SymbolicCheckBoxValueChanged, true);
            app.SymbolicCheckBox.Text = 'Symbolic';
            app.CentralCheckBox = uicheckbox(app.DifferentiationTypesPanel);
            app.CentralCheckBox.ValueChangedFcn = createCallbackFcn(app, @CentralCheckBoxValueChanged, true);
            app.CentralCheckBox.Text = 'Central';
            app.ForwardCheckBox = uicheckbox(app.DifferentiationTypesPanel);
            app.ForwardCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForwardCheckBoxValueChanged, true);
            app.ForwardCheckBox.Text = 'Forward';
            app.BackwardCheckBox = uicheckbox(app.DifferentiationTypesPanel);
            app.BackwardCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackwardCheckBoxValueChanged, true);
            app.BackwardCheckBox.Text = 'Backward';
            app.IntegrationTypesPanel = uipanel(app.UIFigure);
            app.IntegrationTypesPanel.Title = 'Integration Types';
            app.IntegrationTypesPanel.Visible = 'off';
            app.Symbolic_IntCheckBox = uicheckbox(app.IntegrationTypesPanel);
            app.Symbolic_IntCheckBox.ValueChangedFcn = createCallbackFcn(app, @Symbolic_IntCheckBoxValueChanged, true);
            app.Symbolic_IntCheckBox.Text = 'Symbolic_Int';
            app.Simpsons38ruleCheckBox = uicheckbox(app.IntegrationTypesPanel);
            app.Simpsons38ruleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Simpsons38ruleCheckBoxValueChanged, true);
            app.Simpsons38ruleCheckBox.Text = 'Simpson''s 3/8 rule';
            app.Simpsons13ruleCheckBox = uicheckbox(app.IntegrationTypesPanel);
            app.Simpsons13ruleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Simpsons13ruleCheckBoxValueChanged, true);
            app.Simpsons13ruleCheckBox.Text = 'Simpson''s 1/3 rule';
            app.TrapizoidalmethodCheckBox = uicheckbox(app.IntegrationTypesPanel);
            app.TrapizoidalmethodCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrapizoidalmethodCheckBoxValueChanged, true);
            app.TrapizoidalmethodCheckBox.Text = 'Trapizoidal method';
            app.LTTypesPanel = uipanel(app.UIFigure);
            app.LTTypesPanel.Title = 'LT Types';
            app.LTTypesPanel.Visible = 'off';
            app.LaplaceCheckBox = uicheckbox(app.LTTypesPanel);
            app.LaplaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaplaceCheckBoxValueChanged, true);
            app.LaplaceCheckBox.Text = 'Laplace';
            app.InverseLaplaceCheckBox = uicheckbox(app.LTTypesPanel);
            app.InverseLaplaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @InverseLaplaceCheckBoxValueChanged, true);
            app.InverseLaplaceCheckBox.Text = 'Inverse Laplace';
            app.RESULTLabel = uilabel(app.UIFigure);
            app.RESULTLabel.Text = 'RESULT';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            app.MATHLABLabel = uilabel(app.UIFigure);
            app.MATHLABLabel.Text = 'MATHLAB ';
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.Enable = 'off';
            app.GeneratePlotButton = uibutton(app.UIFigure, 'state');
            app.GeneratePlotButton.ValueChangedFcn = createCallbackFcn(app, @GeneratePlotButtonValueChanged, true);
            app.GeneratePlotButton.Enable = 'off';
            app.GeneratePlotButton.Text = 'Generate Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATHLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end